classdef myrobotapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        SimulationtimeLabel  matlab.ui.control.Label
        StartStopButton      matlab.ui.control.Button
        TimeLabel            matlab.ui.control.Label
        DropDown             matlab.ui.control.DropDown
        DpaceSpinnerLabel    matlab.ui.control.Label
        DpaceSpinner         matlab.ui.control.Spinner
        UIAxes               matlab.ui.control.UIAxes
        UIAxes_2             matlab.ui.control.UIAxes
        UIAxes_3             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        w % world
        canvas % canvas
        desired = [];
        actual = [];
        position =[];
        time = [];
        h1 % speed plot handle
        h2 % longitudal plot handle
        h3 % lateral plot handle
        vr_data = [];
        vr_log={};
        b=[];
        robot;
        robotnode;
        vrIdx=0;
    end
    
    methods (Access = private)
        
        function updateWRL(app,w,vr_data,robot)
            %tic
            for jj=1:robot.NumBodies-1
                tform=vr_data((jj-1)*4+1:jj*4,:);
                axang=tform2axang(tform);
                trvec=tform2trvec(tform);
%                 app.robotnode{jj}.translation = vr.import.permuteCoord(trvec, 'Gazebo');
%                 app.robotnode{jj}.rotation = [vr.import.permuteCoord(axang, 'Gazebo'), axang(4)];
                trans=vr.import.permuteCoord(trvec, 'Gazebo');
                rot=[vr.import.permuteCoord(axang, 'Gazebo'), axang(4)];
                app.robotnode{jj}.translation = trans;
                app.robotnode{jj}.rotation = rot;
            end
            %toc
            %drawnow limitrate;
            vrdrawnow;
        end
        
        function processOutputs(app, opIdx, time, data)
            if isequal(app.StartStopButton.Text,'Starting')
                % after first step, enable stop button
                app.StartStopButton.Text = 'Stop';
                app.StartStopButton.Enable = 'on';
            end
            switch opIdx
                case 1
                    app.time=[app.time;time];
                    app.desired=[app.desired ; data'];
                    app.TimeLabel.Text= num2str(time);
                    if size(app.desired,1)<3
                        app.h1=plot(app.UIAxes,app.time,app.desired);
                    else
                        tmat=repmat(app.time',7,1);
                        set(app.h1,{'XData'},num2cell(tmat,2),{'YData'},num2cell(app.desired',2));
                    end
                case 2
                    app.actual=[app.actual ; data'];
                    
                    if size(app.actual,1)<3
                        app.h2=plot(app.UIAxes_2,app.time,app.actual);
                    else
                        tmat=repmat(app.time',7,1);
                        set(app.h2,{'XData'},num2cell(tmat,2),{'YData'},num2cell(app.actual',2));
                    end
                case 3
                    app.position=[app.position ; data'];
                    if size(app.position,1)<3
                        app.h3=plot(app.UIAxes_3,app.time,app.position);
                    else
                        tmat=repmat(app.time',3,1);
                        set(app.h3,{'XData'},num2cell(tmat,2),{'YData'},num2cell(app.position',2));
                    end
                case 4
                    app.vr_data = data;
            end
            if opIdx==4
                if time>=app.vrIdx                   
                    app.updateWRL(app.w,app.vr_data,app.robot);
                    app.vrIdx=app.vrIdx+app.DpaceSpinner.Value;
                end
                drawnow;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp('Initializing ...');
            vrsetpref('DefaultFigureCulling', 'off')
            load_simulink;
            app.UIAxes.Toolbar.Visible = 'off';
            app.UIAxes_2.Toolbar.Visible = 'off';
            app.UIAxes_3.Toolbar.Visible = 'off';
            app.w = vrworld('workcell_kinova.wrl');
            open(app.w);
            reload(app.w);
            %view(app.w);
            fig = vrfigure(app.w);
            
            set(fig,'Viewpoint','Robot_Overview');
            app.robot=loadrobot('kinovaGen3','DataFormat','row','Gravity',[0 0 -9.81]);
            for j=1:app.robot.NumBodies-1
                app.robotnode{j} = vrnode(app.w,['robot_' app.robot.BodyNames{j}]);
            end
            disp('Initializing ... done');
        end

        % Button pushed function: StartStopButton
        function StartStopButtonPushed(app, event)
            if isequal(app.StartStopButton.Text,'Start')
                app.StartStopButton.Text = 'Starting';
                app.StartStopButton.Enable = 'off';
                drawnow;
                try
                    reload(app.w);
                    app.desired=[];
                    app.actual=[];
                    app.position=[];
                    app.time=[];
                    app.vr_data=[];
                    app.vrIdx=0;
                    
                    switch app.DropDown.Value
                        case 1
                            t=load("trajectories2.mat","t1");
                            traj=t.t1;
                        case 2
                            t=load("trajectories2.mat","t2");
                            traj=t.t2;
                        case 3
                            t=load("trajectories2.mat","t3");
                            traj=t.t3;
                    end
                    
                    wp=traj(:,2:8)';
                    wpti=[traj(1,1) traj(end,1)];
                    
                    simImp = Simulink.SimulationInput('myrobot');
                    simImp = simImp.setModelParameter('StopTime',num2str(traj(end,1)));
                    simImp = simImp.setVariable('wp',wp);
                    simImp = simImp.setVariable('wpti',wpti);
                    simImp = simulink.compiler.setExternalOutputsFcn(simImp, @app.processOutputs);                  
                    simImp = simulink.compiler.configureForDeployment(simImp);
                    sim(simImp);
                catch E
                    uialert(app.UIFigure, getReport(E), E.identifier);
                end
            else % stop button pressed
                simulink.compiler.stopSimulation('myrobot');
            end
            app.StartStopButton.Text = 'Start';
            app.StartStopButton.Enable = 'on';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            vrclose all
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SimulationtimeLabel = uilabel(app.UIFigure);
            app.SimulationtimeLabel.Text = 'Simulation time:';
            app.StartStopButton = uibutton(app.UIFigure, 'push');
            app.StartStopButton.ButtonPushedFcn = createCallbackFcn(app, @StartStopButtonPushed, true);
            app.StartStopButton.Text = 'Start';
            app.TimeLabel = uilabel(app.UIFigure);
            app.TimeLabel.Text = 'Time';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Trajectory 1', 'Trajectory 2', 'Trajectory 3'};
            app.DropDown.ItemsData = [1 2 3];
            app.DropDown.Value = 1;
            app.DpaceSpinnerLabel = uilabel(app.UIFigure);
            app.DpaceSpinnerLabel.Text = '3D pace';
            app.DpaceSpinner = uispinner(app.UIFigure);
            app.DpaceSpinner.Step = 0.1;
            app.DpaceSpinner.Limits = [0.1 1];
            app.DpaceSpinner.Value = 0.2;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Desired Config')
            app.UIAxes.XLim = [0 33];
            app.UIAxes.YLim = [-3.15 3.15];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Actual Config')
            app.UIAxes_2.XLim = [0 33];
            app.UIAxes_2.YLim = [-3.15 3.15];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Position')
            app.UIAxes_3.XLim = [0 33];
            app.UIAxes_3.YLim = [-1 2];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = myrobotapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end